classdef SwapPricingSimple < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        TabGroup                       matlab.ui.container.TabGroup
        PriceSwapTab                   matlab.ui.container.Tab
        GridLayout2                    matlab.ui.container.GridLayout
        SwapPriceDiscountEditField     matlab.ui.control.NumericEditField
        SwapPriceDiscountLabel         matlab.ui.control.Label
        PriceSwapButton                matlab.ui.control.Button
        LoadYieldCurveButton           matlab.ui.control.Button
        UITable                        matlab.ui.control.Table
        Leg2RateSpinner                matlab.ui.control.Spinner
        Leg2RateSpinnerLabel           matlab.ui.control.Label
        Leg1RateSpinner                matlab.ui.control.Spinner
        Leg1RateSpinnerLabel           matlab.ui.control.Label
        Leg2TypeDropDown               matlab.ui.control.DropDown
        Leg2TypeDropDownLabel          matlab.ui.control.Label
        Leg1TypeDropDown               matlab.ui.control.DropDown
        Leg1TypeDropDownLabel          matlab.ui.control.Label
        MaturityDateDatePicker         matlab.ui.control.DatePicker
        MaturityDateDatePickerLabel    matlab.ui.control.Label
        SettleDateDatePicker           matlab.ui.control.DatePicker
        SettleDateDatePickerLabel      matlab.ui.control.Label
        PricePortfolioTab              matlab.ui.container.Tab
        GridLayout3                    matlab.ui.container.GridLayout
        SetupParallelPoolButton        matlab.ui.control.Button
        NumberofLocalWorkersEditField  matlab.ui.control.NumericEditField
        NumberofLocalWorkersLabel      matlab.ui.control.Label
        ClearButton                    matlab.ui.control.Button
        CalculationStatusLamp          matlab.ui.control.Lamp
        CalculationStatusLampLabel     matlab.ui.control.Label
        TotalPortfolioValueEditField   matlab.ui.control.NumericEditField
        TotalPortfolioValueEditFieldLabel  matlab.ui.control.Label
        PricePortfolioButton           matlab.ui.control.Button
        ImportYieldCurveButton         matlab.ui.control.Button
        LoadPortfolioButton            matlab.ui.control.Button
        UITableYieldRates              matlab.ui.control.Table
        UITablePortfolio               matlab.ui.control.Table
    end


    properties (Access = private)
        ZeroRates % Rates from getYieldCurveFRED()
        ZeroTimesMonths % Rate month numbers from getYieldCurveFRED()
        SingleSwapPrice % Price of a single swap
        SwapPortfolio % Table of information about the portfolio of swaps
        SwapPortfolioTotalValue % Description
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ImportYieldCurveButton, 
        % ...and 1 other component
        function LoadYieldCurveButtonPushed(app, event)
            [app.ZeroRates, app.ZeroTimesMonths, seriesIDs] = getYieldCurveFRED();

            % Set all tables
            app.UITable.ColumnName = seriesIDs;
            app.UITable.Data = [app.ZeroRates];
            app.UITableYieldRates.ColumnName = seriesIDs;
            app.UITableYieldRates.Data = [app.ZeroRates];
            %% Could be a switch
            % Adding a switch depending on which button calls it
            % switch event.Source.Text
            %     case app.LoadYieldCurveButton.Text % Text on button for single Swap
            %         app.UITable.ColumnName = seriesIDs;
            %         app.UITable.Data = [app.ZeroRates];
            %
            %     case  app.ImportYieldCurveButton.Text % Text on button for single Swap
            %         app.UITableYieldRates.ColumnName = seriesIDs;
            %         app.UITableYieldRates.Data = [app.ZeroRates];
            % end

        end

        % Button pushed function: LoadPortfolioButton
        function LoadPortfolioButtonPushed(app, event)
            % Eventually this will all be from Snowflake
            swapData = getSwapsMAT("swapData.mat"); %Eventually from Snowflak
            %% Fake number of assets in the portfolio
            minValue = 35;
            maxValue = 100;

            % Generate random numbers
            Number = round(minValue + (maxValue - minValue) * rand(height(swapData), 1));

            %% Rest of the data
            ID = (1:height(swapData))';
            Price = zeros(height(swapData), 1);
            Data = [table(ID) swapData table(Number) table(Price)];
            app.SwapPortfolio = Data;
            app.UITablePortfolio.Data = Data;
            % Think about adding colors to the table initially when prices
            % are not set

        end

        % Cell edit callback: UITable, UITableYieldRates
        function UITableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            % Update UI in both places
            app.UITable.Data(indices(1),indices(2)) = newData;
            app.UITableYieldRates.Data(indices(1),indices(2)) = newData;
            % Update Data
            app.ZeroRates = app.UITable.Data;
        end

        % Button pushed function: PriceSwapButton
        function PriceSwapButtonPushed(app, event)
            % Setting values
            CurveType = "zero";
            Settle = app.SettleDateDatePicker.Value;
            ZeroTimesMonths = app.ZeroTimesMonths;
            ZeroRates = app.ZeroRates;
            MaturityDate = app.MaturityDateDatePicker.Value;
            Leg1Type = app.Leg1TypeDropDown.Value;
            Leg1Rate = app.Leg1RateSpinner.Value;
            Leg2Type = app.Leg2TypeDropDown.Value;
            Leg2Rate = app.Leg2RateSpinner.Value;

            % Price Swap Locally
            [app.SingleSwapPrice, ~] = priceSwapDiscount(...
                CurveType,...
                app.SettleDateDatePicker.Value,...
                app.ZeroTimesMonths,...
                app.ZeroRates,...
                app.MaturityDateDatePicker.Value,...
                app.Leg1TypeDropDown.Value,...
                app.Leg1RateSpinner.Value,...
                app.Leg2TypeDropDown.Value,...
                app.Leg2RateSpinner.Value);

            % Set Price in UI
            app.SwapPriceDiscountEditField.Value = app.SingleSwapPrice;
        end

        % Button pushed function: PricePortfolioButton
        function PricePortfolioButtonPushed(app, event)
            % info = gcp;
            % app.NumberofLocalWorkersEditField.Value = info.NumWorkers;
            app.CalculationStatusLamp.Color = [0.07,0.62,1.00];
            app.CalculationStatusLampLabel.Text = "Calculation running...";
            drawnow
            Prices = app.SwapPortfolio.Price;
            SwapPortfolio = app.SwapPortfolio; % This is because you cannot send app information to a parpool
            ZeroTimesMonths = app.ZeroTimesMonths;
            ZeroRates = app.ZeroRates;
            % parfor i = 1:height(app.SwapPortfolio)
            for i = 1:height(app.SwapPortfolio)

                % Setting values
                CurveType = "zero";
                Settle = SwapPortfolio.SettleDate(i);
                % ZeroTimesMonths = ZeroTimesMonths;
                % ZeroRates = ZeroRates;
                MaturityDate = SwapPortfolio.MaturityDate(i);
                Leg1Type = SwapPortfolio.Leg1Type(i);
                Leg1Rate = SwapPortfolio.Leg1Rate(i);
                Leg2Type = SwapPortfolio.Leg2Type(i);
                Leg2Rate = SwapPortfolio.Leg2Rate(i);
                
                Prices(i) = priceSwapDiscount(...
                CurveType,...
                Settle,...
                ZeroTimesMonths,...
                ZeroRates,...
                MaturityDate,...
                Leg1Type,...
                Leg1Rate,...
                Leg2Type,...
                Leg2Rate);
            end

            % Now set the property and display the values
            app.SwapPortfolio.Price = Prices;
            app.UITablePortfolio.Data = app.SwapPortfolio.Variables;
            app.SwapPortfolioTotalValue = dot(app.SwapPortfolio.Number,app.SwapPortfolio.Price);
            app.TotalPortfolioValueEditField.Value = app.SwapPortfolioTotalValue;
            app.CalculationStatusLampLabel.Text = "Calculation Complete";
            app.CalculationStatusLamp.Color = [0.39,0.83,0.07];
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            app.SwapPortfolio.Price(:) = 0;
            app.UITablePortfolio.Data = app.SwapPortfolio.Variables;
            app.TotalPortfolioValueEditField.Value = 0;
            if app.NumberofLocalWorkersEditField.Value > 0
                app.CalculationStatusLamp.Color = [0.07,0.62,1.00];
                app.CalculationStatusLampLabel.Text = "Ready for calculations";
            else % Assumes no parallel computing
                app.CalculationStatusLamp.Color = [1,1,1];
                app.CalculationStatusLampLabel.Text = "Calculation Status";
            end

        end

        % Button pushed function: SetupParallelPoolButton
        function SetupParallelPoolButtonPushed(app, event)
            app.CalculationStatusLamp.Color = [1.00,1.00,0.07];
            app.CalculationStatusLampLabel.Text = "Setting up resources";
            drawnow
            %% No parpool resources in MATLAB Online
            % info = gcp;
            % app.NumberofLocalWorkersEditField.Value = info.NumWorkers;
            pause(5)
            app.NumberofLocalWorkersEditField.Value = 2;
            app.CalculationStatusLamp.Color = [0.07,0.62,1.00];
            app.CalculationStatusLampLabel.Text = "Ready for calculations";
            drawnow
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.TabGroup = uitabgroup(app.GridLayout);
            app.PriceSwapTab = uitab(app.TabGroup);
            app.PriceSwapTab.Title = 'Price Swap';
            app.GridLayout2 = uigridlayout(app.PriceSwapTab);
            app.SettleDateDatePickerLabel = uilabel(app.GridLayout2);
            app.SettleDateDatePickerLabel.Text = 'Settle Date';
            app.SettleDateDatePicker = uidatepicker(app.GridLayout2);
            app.SettleDateDatePicker.Value = datetime([2019 9 15]);
            app.MaturityDateDatePickerLabel = uilabel(app.GridLayout2);
            app.MaturityDateDatePickerLabel.Text = 'Maturity Date';
            app.MaturityDateDatePicker = uidatepicker(app.GridLayout2);
            app.MaturityDateDatePicker.Value = datetime([2024 9 15]);
            app.Leg1TypeDropDownLabel = uilabel(app.GridLayout2);
            app.Leg1TypeDropDownLabel.Text = 'Leg 1 Type';
            app.Leg1TypeDropDown = uidropdown(app.GridLayout2);
            app.Leg1TypeDropDown.Items = {'Fixed', 'Float'};
            app.Leg1TypeDropDown.Value = 'Float';
            app.Leg2TypeDropDownLabel = uilabel(app.GridLayout2);
            app.Leg2TypeDropDownLabel.Text = 'Leg 2 Type';
            app.Leg2TypeDropDown = uidropdown(app.GridLayout2);
            app.Leg2TypeDropDown.Items = {'Fixed', 'Float'};
            app.Leg2TypeDropDown.Value = 'Fixed';
            app.Leg1RateSpinnerLabel = uilabel(app.GridLayout2);
            app.Leg1RateSpinnerLabel.Text = 'Leg 1 Rate';
            app.Leg1RateSpinner = uispinner(app.GridLayout2);
            app.Leg1RateSpinner.Step = 0.001;
            app.Leg1RateSpinner.Value = 0.07;
            app.Leg2RateSpinnerLabel = uilabel(app.GridLayout2);
            app.Leg2RateSpinnerLabel.Text = 'Leg 2 Rate';
            app.Leg2RateSpinner = uispinner(app.GridLayout2);
            app.Leg2RateSpinner.Step = 0.001;
            app.Leg2RateSpinner.Value = 0.03;
            app.UITable = uitable(app.GridLayout2);
            app.UITable.ColumnName = {'''DGS1MO'''; '''DGS3MO'''; '''DGS6MO'''; '''DGS1'''; '''DGS2'''; '''DGS3'''; '''DGS5'''; '''DGS7'''; '''DGS10'''; '''DGS20'''; '''DGS30'''};
            app.UITable.RowName = {};
            app.UITable.ColumnSortable = true;
            app.UITable.ColumnEditable = true;
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.LoadYieldCurveButton = uibutton(app.GridLayout2, 'push');
            app.LoadYieldCurveButton.ButtonPushedFcn = createCallbackFcn(app, @LoadYieldCurveButtonPushed, true);
            app.LoadYieldCurveButton.Text = 'Load Yield Curve';
            app.PriceSwapButton = uibutton(app.GridLayout2, 'push');
            app.PriceSwapButton.ButtonPushedFcn = createCallbackFcn(app, @PriceSwapButtonPushed, true);
            app.PriceSwapButton.Text = 'Price Swap';
            app.SwapPriceDiscountLabel = uilabel(app.GridLayout2);
            app.SwapPriceDiscountLabel.Text = 'Swap Price (Discount)';
            app.SwapPriceDiscountEditField = uieditfield(app.GridLayout2, 'numeric');
            app.SwapPriceDiscountEditField.ValueDisplayFormat = '$%.2f';
            app.PricePortfolioTab = uitab(app.TabGroup);
            app.PricePortfolioTab.Title = 'Price Portfolio';
            app.GridLayout3 = uigridlayout(app.PricePortfolioTab);
            app.UITablePortfolio = uitable(app.GridLayout3);
            app.UITablePortfolio.ColumnName = {'ID'; 'SettleDate'; 'MaturityDate'; 'Leg1Type'; 'Leg1Rate'; 'Leg2Type'; 'Leg2Rate'; 'Number'; 'Price'};
            app.UITablePortfolio.RowName = {};
            app.UITablePortfolio.ColumnSortable = true;
            app.UITableYieldRates = uitable(app.GridLayout3);
            app.UITableYieldRates.ColumnName = {'''DGS1MO'''; '''DGS3MO'''; '''DGS6MO'''; '''DGS1'''; '''DGS2'''; '''DGS3'''; '''DGS5'''; '''DGS7'''; '''DGS10'''; '''DGS20'''; '''DGS30'''};
            app.UITableYieldRates.RowName = {};
            app.UITableYieldRates.ColumnEditable = true;
            app.UITableYieldRates.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.LoadPortfolioButton = uibutton(app.GridLayout3, 'push');
            app.LoadPortfolioButton.ButtonPushedFcn = createCallbackFcn(app, @LoadPortfolioButtonPushed, true);
            app.LoadPortfolioButton.Text = 'Load Portfolio';
            app.ImportYieldCurveButton = uibutton(app.GridLayout3, 'push');
            app.ImportYieldCurveButton.ButtonPushedFcn = createCallbackFcn(app, @LoadYieldCurveButtonPushed, true);
            app.ImportYieldCurveButton.Text = 'Import Yield Curve';
            app.PricePortfolioButton = uibutton(app.GridLayout3, 'push');
            app.PricePortfolioButton.ButtonPushedFcn = createCallbackFcn(app, @PricePortfolioButtonPushed, true);
            app.PricePortfolioButton.Text = 'Price Portfolio';
            app.TotalPortfolioValueEditFieldLabel = uilabel(app.GridLayout3);
            app.TotalPortfolioValueEditFieldLabel.Text = 'Total Portfolio Value';
            app.TotalPortfolioValueEditField = uieditfield(app.GridLayout3, 'numeric');
            app.TotalPortfolioValueEditField.ValueDisplayFormat = '$%.2f';
            app.TotalPortfolioValueEditField.Editable = 'off';
            app.CalculationStatusLampLabel = uilabel(app.GridLayout3);
            app.CalculationStatusLampLabel.Text = 'Calculation Status';
            app.CalculationStatusLamp = uilamp(app.GridLayout3);
            app.ClearButton = uibutton(app.GridLayout3, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'Clear';
            app.NumberofLocalWorkersLabel = uilabel(app.GridLayout3);
            app.NumberofLocalWorkersLabel.Text = 'Number of Local Workers';
            app.NumberofLocalWorkersEditField = uieditfield(app.GridLayout3, 'numeric');
            app.NumberofLocalWorkersEditField.Editable = 'off';
            app.SetupParallelPoolButton = uibutton(app.GridLayout3, 'push');
            app.SetupParallelPoolButton.ButtonPushedFcn = createCallbackFcn(app, @SetupParallelPoolButtonPushed, true);
            app.SetupParallelPoolButton.Text = 'Setup Parallel Pool';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SwapPricingSimple

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end